classdef bioreactor_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        HeartValveBioreactorLabel       matlab.ui.control.Label
        OperatorDropDownLabel           matlab.ui.control.Label
        OperatorDropDown                matlab.ui.control.DropDown
        NewButton                       matlab.ui.control.Button
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        NewButton_2                     matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        RUNSwitchLabel                  matlab.ui.control.Label
        RUNSwitch                       matlab.ui.control.Switch
        PeakPressureEditFieldLabel      matlab.ui.control.Label
        PeakPressureEditField           matlab.ui.control.NumericEditField
        SaveDataCheckBox                matlab.ui.control.CheckBox
        SaveLocationHVBioreactorDataLabel  matlab.ui.control.Label
        MinPressureEditFieldLabel       matlab.ui.control.Label
        MinPressureEditField            matlab.ui.control.NumericEditField
        DataAcquisitionFrequencyHzEditFieldLabel  matlab.ui.control.Label
        DataAcquisitionFrequencyHzEditField  matlab.ui.control.NumericEditField
        SystemReadyLampLabel            matlab.ui.control.Label
        SystemReadyLamp                 matlab.ui.control.Lamp
        TimeForwardEditFieldLabel       matlab.ui.control.Label
        TimeForwardEditField            matlab.ui.control.NumericEditField
        TimeBackpressureEditFieldLabel  matlab.ui.control.Label
        TimeBackpressureEditField       matlab.ui.control.NumericEditField
        TimeRestEditFieldLabel          matlab.ui.control.Label
        TimeRestEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        time_for % time for forward pulse of heartbeat
        time_back % time for backpressure pulse of heartbeat
        time_rest % time for rest between heartbeats
        peak % peak pressure reached during heartbeat
        min % min pressure reached during heartbeat
    end
    
    methods (Access = private)
        
        % Draw new plot on axes
        function update_main_plot(app, x_data, y_data)
            main_plot = app.UIAxes;
            plot(main_plot, x_data, y_data, 'LineWidth', 2);
        end
        
        % Define heartbeat waveform using sequential quadratic functions
        function [x_wave, y_wave] = update_heartbeat_waveform(app)
            
            % Calculate length of heartbeat
            time_inc = 0.001;
            x_wave_beat = 0:time_inc:(app.time_for + app.time_back);
            x_wave_rest = 0:time_inc:app.time_rest;
            
            % Define points for pchip to force waveform to cross through
            time_min_net = app.time_back/2 + app.time_for;
            time_back_net = app.time_back + app.time_for;
            x = [0 app.time_for/2 time_min_net time_back_net];
            y = [0 app.peak app.min 0];
            y_wave_beat = pchip(x, y, x_wave_beat);
            
            % Rest segment
            y_wave_rest = x_wave_rest.*0;
            
            % Collect segments and update main plot
            x_wave = 0:time_inc:(app.time_for + app.time_back + app.time_rest);
            y_wave = [y_wave_beat y_wave_rest(2:end)];
            update_main_plot(app, x_wave, y_wave);
        end
        
        % Turn bioreactor system light on or off
        function on_off_light(app, mode)
            switch lower(mode)
                case 'on'
                    app.Lamp.Color = 'green';
                case 'off'
                    app.Lamp.Color = 'red';
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lab_data)
            
        end

        % Value changed function: RUNSwitch
        function runBioreactor(app, event)
            value = app.RUNSwitch.Value;
            
            % Change system light
            on_off_light(app, value);
            
        end

        % Button pushed function: NewButton
        function new_operator(app, event)
            
        end

        % Value changed function: OperatorDropDown
        function operator_change(app, event)
            value = app.OperatorDropDown.Value;
            
            %%% DEMO HARD CODE %%%
            if strcmp(value, 'Ted')
                app.time_for = 0.5;
                app.time_back = 0.1;
                app.time_rest = 1;
                app.peak = 50;
                app.min = -10;
                update_heartbeat_waveform(app);
            end
            
        end

        % Value changed function: PeakPressureEditField
        function peak_pressure_change(app, event)
            value = app.PeakPressureEditField.Value;
            app.peak = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: MinPressureEditField
        function min_pressure_change(app, event)
            value = app.MinPressureEditField.Value;
            app.min = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: TimeForwardEditField
        function time_for_change(app, event)
            value = app.TimeForwardEditField.Value;
            app.time_for = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: TimeBackpressureEditField
        function time_back_change(app, event)
            value = app.TimeBackpressureEditField.Value;
            app.time_back = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: TimeRestEditField
        function time_rest_change(app, event)
            value = app.TimeRestEditField.Value;
            app.time_rest = value;
            update_heartbeat_waveform(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Pressure [mmHg]')
            app.HeartValveBioreactorLabel = uilabel(app.UIFigure);
            app.HeartValveBioreactorLabel.Text = 'Heart Valve Bioreactor';
            app.OperatorDropDownLabel = uilabel(app.UIFigure);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorDropDown = uidropdown(app.UIFigure);
            app.OperatorDropDown.Items = {'Operator', 'Ted'};
            app.OperatorDropDown.ValueChangedFcn = createCallbackFcn(app, @operator_change, true);
            app.OperatorDropDown.Value = 'Operator';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @new_operator, true);
            app.NewButton.Text = 'New';
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.Items = {'Single Valve'};
            app.ExperimentDropDown.Value = 'Single Valve';
            app.NewButton_2 = uibutton(app.UIFigure, 'push');
            app.NewButton_2.Text = 'New';
            app.Lamp = uilamp(app.UIFigure);
            app.RUNSwitchLabel = uilabel(app.UIFigure);
            app.RUNSwitchLabel.Text = 'RUN';
            app.RUNSwitch = uiswitch(app.UIFigure, 'slider');
            app.RUNSwitch.ValueChangedFcn = createCallbackFcn(app, @runBioreactor, true);
            app.PeakPressureEditFieldLabel = uilabel(app.UIFigure);
            app.PeakPressureEditFieldLabel.Text = {'Peak'; 'Pressure'};
            app.PeakPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakPressureEditField.ValueChangedFcn = createCallbackFcn(app, @peak_pressure_change, true);
            app.SaveDataCheckBox = uicheckbox(app.UIFigure);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveLocationHVBioreactorDataLabel = uilabel(app.UIFigure);
            app.SaveLocationHVBioreactorDataLabel.Text = 'Save Location: .../HVBioreactor/Data';
            app.MinPressureEditFieldLabel = uilabel(app.UIFigure);
            app.MinPressureEditFieldLabel.Text = {'Min'; 'Pressure'};
            app.MinPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPressureEditField.ValueChangedFcn = createCallbackFcn(app, @min_pressure_change, true);
            app.DataAcquisitionFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.DataAcquisitionFrequencyHzEditFieldLabel.Text = {'Data Acquisition'; 'Frequency (Hz)'};
            app.DataAcquisitionFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemReadyLampLabel = uilabel(app.UIFigure);
            app.SystemReadyLampLabel.Text = 'System Ready';
            app.SystemReadyLamp = uilamp(app.UIFigure);
            app.TimeForwardEditFieldLabel = uilabel(app.UIFigure);
            app.TimeForwardEditFieldLabel.Text = {'Time'; 'Forward'};
            app.TimeForwardEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeForwardEditField.ValueChangedFcn = createCallbackFcn(app, @time_for_change, true);
            app.TimeBackpressureEditFieldLabel = uilabel(app.UIFigure);
            app.TimeBackpressureEditFieldLabel.Text = {'Time'; 'Backpressure'};
            app.TimeBackpressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeBackpressureEditField.ValueChangedFcn = createCallbackFcn(app, @time_back_change, true);
            app.TimeRestEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRestEditFieldLabel.Text = {'Time'; 'Rest'};
            app.TimeRestEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeRestEditField.ValueChangedFcn = createCallbackFcn(app, @time_rest_change, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bioreactor_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end