classdef bioreactor_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BioreactorMenu                  matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        OperatorDropDownLabel           matlab.ui.control.Label
        OperatorDropDown                matlab.ui.control.DropDown
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        SystemRunningLamp               matlab.ui.control.Lamp
        RUNSwitchLabel                  matlab.ui.control.Label
        RUNSwitch                       matlab.ui.control.Switch
        PeakPressureEditFieldLabel      matlab.ui.control.Label
        PeakPressureEditField           matlab.ui.control.NumericEditField
        SaveDataCheckBox                matlab.ui.control.CheckBox
        SaveLocationHVBioreactorDataLabel  matlab.ui.control.Label
        MinPressureEditFieldLabel       matlab.ui.control.Label
        MinPressureEditField            matlab.ui.control.NumericEditField
        DataAcquisitionFrequencyHzEditFieldLabel  matlab.ui.control.Label
        DataAcquisitionFrequencyHzEditField  matlab.ui.control.NumericEditField
        SystemReadyLampLabel            matlab.ui.control.Label
        SystemReadyLamp                 matlab.ui.control.Lamp
        TimeForwardEditFieldLabel       matlab.ui.control.Label
        TimeForwardEditField            matlab.ui.control.NumericEditField
        TimeBackpressureEditFieldLabel  matlab.ui.control.Label
        TimeBackpressureEditField       matlab.ui.control.NumericEditField
        TimeRestEditFieldLabel          matlab.ui.control.Label
        TimeRestEditField               matlab.ui.control.NumericEditField
        UPDATEBIOREACTORButton          matlab.ui.control.Button
        CONNECTBIOREACTORButton         matlab.ui.control.Button
        BIOREACTOROFFLabel              matlab.ui.control.Label
        PlotDataCheckBox                matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        lab_data % saved lab data pulled from an excel sheet with all members
        time_for % time for forward pulse of heartbeat
        time_back % time for backpressure pulse of heartbeat
        time_rest % time for rest between heartbeats
        peak % peak pressure reached during heartbeat
        min % min pressure reached during heartbeat
        serial_port % serial port address for bioreactor
        bioreactor_read_timer % timer to read from sensors
        data_freq % frequency at which to read data
        num_measurements % number of data measurements
        bioreactor_data_mat % matrix that stores all bioreactor data over plotting window
        bioreactor_data_count % counter to find when to clear the main figure of incoming data
        data_line_object % line object for incoming bioreactor data
    end
    
    methods (Access = private)
        
        % Define heartbeat waveform using sequential quadratic functions
        function [x_wave, y_wave] = update_heartbeat_waveform(app)
            
            % Calculate length of heartbeat
            time_inc = 0.001;
            x_wave_beat = 0:time_inc:(app.time_for + app.time_back);
            x_wave_rest = 0:time_inc:app.time_rest;
            
            % Define points for pchip to force waveform to cross through
            time_min_net = app.time_back/2 + app.time_for;
            time_back_net = app.time_back + app.time_for;
            x = [0 app.time_for/2 time_min_net time_back_net];
            y = [0 app.peak app.min 0];
            y_wave_beat = pchip(x, y, x_wave_beat);
            
            % Rest segment
            y_wave_rest = x_wave_rest.*0;
            
            % Collect segments and update main plot
            x_wave = 0:time_inc:(app.time_for + app.time_back + app.time_rest);
            y_wave = [y_wave_beat y_wave_rest(2:end)];
            
            % Clear the UI axes because hold is set to on by default, then
            % plot data
            cla(app.UIAxes);
            plot(app.UIAxes, x_wave, y_wave, 'LineWidth', 2);
        end
        
        % Turn bioreactor system light on or off
        function on_off_light(app, mode)
            switch lower(mode)
                case 'on'
                    app.SystemRunningLamp.Color = 'green';
                case 'off'
                    app.SystemRunningLamp.Color = 'red';
            end
        end
        
        % Timer function that receives data from the bioreactor
        function read_bioreactor_data(app)
            if app.PlotDataCheckBox == true | app.SaveDataCheckBox == true
                % Get sensor data values
                while (length(bioreactor_data) ~= (app.num_measurements+1))
                    bioreactor_data = readline(app.serial_port); % Read from serial port
                    bioreactor_data = split(bioreactor_data, ', '); % Split into each measurement 
                end
                    bioreactor_data = str2double(bioreactor_data(1:app.num_measurements)); % Convert measurement strings into doubles


                % If selected, add newly collected data to figure plot
                if app.PlotDataCheckBox.Value == true
                    update_data_plot(app, bioreactor_data);
                end
                
                % If selected, save data to csv file
                if app.SaveDataCheckBox.Value == true
                    save_bioreactor_data(app, bioreactor_data);
                end
            end
            
            % Increment counter to keep track of where we are relative to
            % the heartbeat waveform plot
            app.bioreactor_data_count = app.bioreactor_data_count + 1;
        end
        
        % Plot bioreactor data overlayed on heartbeat plot
        function update_data_plot(app, bioreactor_data)
            app.bioreactor_data_mat = [app.bioreactor_data_mat; bioreactor_data];
            
        end
        
        % Save bioreactor data to csv
        function save_bioreactor_data(app, bioreactor_data)
            
        end

    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lab_data)
            % Load in lab data into gui property
            app.lab_data = lab_data;
            
            % Setup initial operator dropdown
            app.OperatorDropDown.Items = [{'Operator'}, lab_data.users];
            app.OperatorDropDown.Value = 'Operator';
            
            % Setup initial experiment dropdown
            app.ExperimentDropDown.Items = {'Experiment'};
            app.ExperimentDropDown.Value = 'Experiment';
            
            % Setup initial save data checkbox
            app.SaveDataCheckBox.Value = true;
            
            % Setup initial run system components
            app.RUNSwitch.Value = 'Off';
            app.SystemRunningLamp.Color = 'r';
            
            % Initialize serial port property
            app.serial_port = "NULL";
            
            % Initialize data acquisition frequency [Hz]
            app.data_freq = 10;
            app.DataAcquisitionFrequencyHzEditField.Value = 10;
            app.DataAcquisitionFrequencyHzEditField.Limits = [1, 100];
            
            % Set main plot's axes to have hold on indefinitely
            hold(app.UIAxes, 'on');
            
            % Set number of measurements
            % measurements: [time (ms), pump_command (0 - 4095), pump_position (0 - 4095),
            %                   pressure_p1 (mmhg), pressure_p2 (mmhg)]
            app.num_measurements = 5;
            
            % Setup line object for bioreactor data
            app.data_line_object = line(app.UIAxes, 'Visible', 'off');
            
            % Initialize data count
        end

        % Value changed function: RUNSwitch
        function runBioreactor(app, event)
            value = app.RUNSwitch.Value;
            
            % Change system light
            on_off_light(app, value);
        end

        % Value changed function: OperatorDropDown
        function operator_change(app, event)
            value = app.OperatorDropDown.Value;
            
            % Identify user selected from dropdown
            user = find(contains(app.lab_data.users, value));
            
            % Change default fields of gui based on user selected
            app.ExperimentDropDown.Items = app.lab_data.experiments(user,:);
            app.ExperimentDropDown.Value = app.lab_data.experiments{user,1};
            app.PeakPressureEditField.Value = app.lab_data.peak_pressures{user,1};
            app.MinPressureEditField.Value = app.lab_data.min_pressures{user,1};
            app.TimeForwardEditField.Value = app.lab_data.forward_pulse_time{user,1};
            app.TimeBackpressureEditField.Value = app.lab_data.backpressure_time{user,1};      
            app.TimeRestEditField.Value = app.lab_data.rest_time{user,1};
            app.DataAcquisitionFrequencyHzEditField.Value = app.lab_data.acq_freq{user,1};
            app.SaveLocationHVBioreactorDataLabel.Text = strcat('Save Location: ...', app.lab_data.save_loc{user,1});
            
            % Set initial gui property values for loading in heartbeat
            app.time_for = app.lab_data.forward_pulse_time{user,1};
            app.time_back = app.lab_data.backpressure_time{user,1};
            app.time_rest = app.lab_data.rest_time{user,1};
            app.peak = app.lab_data.peak_pressures{user,1};
            app.min = app.lab_data.min_pressures{user,1};  
            
            % Update heartbeat plot to reflect selected user's defaults
            update_heartbeat_waveform(app);
        end

        % Value changed function: PeakPressureEditField
        function peak_pressure_change(app, event)
            value = app.PeakPressureEditField.Value;
            app.peak = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: MinPressureEditField
        function min_pressure_change(app, event)
            value = app.MinPressureEditField.Value;
            app.min = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: TimeForwardEditField
        function time_for_change(app, event)
            value = app.TimeForwardEditField.Value;
            app.time_for = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: TimeBackpressureEditField
        function time_back_change(app, event)
            value = app.TimeBackpressureEditField.Value;
            app.time_back = value;
            update_heartbeat_waveform(app);
        end

        % Value changed function: TimeRestEditField
        function time_rest_change(app, event)
            value = app.TimeRestEditField.Value;
            app.time_rest = value;
            update_heartbeat_waveform(app);            
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            value = app.ExperimentDropDown.Value;
            
            % Identify user and the experiment selected
            user = find(contains(app.lab_data.users, app.OperatorDropDown.Value));
            exp = find(contains(app.lab_data.experiments(user,:), value));

            % Update gui buttons and labels
            app.ExperimentDropDown.Value = app.lab_data.experiments{user,exp};
            app.PeakPressureEditField.Value = app.lab_data.peak_pressures{user,exp};
            app.MinPressureEditField.Value = app.lab_data.min_pressures{user,exp};
            app.TimeForwardEditField.Value = app.lab_data.forward_pulse_time{user,exp};
            app.TimeBackpressureEditField.Value = app.lab_data.backpressure_time{user,exp};     
            app.TimeRestEditField.Value = app.lab_data.rest_time{user,exp};
            app.DataAcquisitionFrequencyHzEditField.Value = app.lab_data.acq_freq{user,exp};
            app.SaveLocationHVBioreactorDataLabel.Text = strcat('Save Location: ...', app.lab_data.save_loc{user,exp});
            
            % Set initial gui property values for loading in heartbeat
            app.time_for = app.lab_data.forward_pulse_time{user,exp};
            app.time_back = app.lab_data.backpressure_time{user,exp};
            app.time_rest = app.lab_data.rest_time{user,exp};
            app.peak = app.lab_data.peak_pressures{user,exp};
            app.min = app.lab_data.min_pressures{user,exp}; 
            
            % Update heartbeat plot to reflect selected user's defaults
            update_heartbeat_waveform(app);
        end

        % Button pushed function: CONNECTBIOREACTORButton
        function connect_bioreactor(app, event)
            available_ports = serialportlist("available");
            
            % Check if there are no available ports
            if ~isempty(available_ports)
                % If there are ports available, cycle through them to find
                % the bioreactor's arduino
                for i = 1:length(available_ports)
                    possible_port = serialport(available_ports(i));
                    
                    % Test each port by sending 5, appropriate response is
                    % 10
                    write(possible_port, 5, 'uint8')
                    if read(possible_port, 1, 'uint8') == 10
                        app.serial_port = possible_port;
                        disp("Successfully connected to bioreactor");
                        
                        % Start timer function that pulls data from the
                        % bioreactor, wait 1 second before running first
                        % collection of data
                        flush(app.serial_port);
                        app.bioreactor_read_timer = timer('TimerFcn', {@read_bioreactor_data, app}, 'Period', app.data_freq, 'StartDelay', 1);
                    end
                end
            else
                disp("Error: No ports were detected as the bioreactor");
            end
        end

        % Button pushed function: UPDATEBIOREACTORButton
        function update_bioreactor(app, event)
            
        end

        % Value changed function: 
        % DataAcquisitionFrequencyHzEditField
        function data_freq_changed(app, event)
            value = app.DataAcquisitionFrequencyHzEditField.Value;
            
            % Update data frequency
            app.data_freq = value;
            
            % If connected to bioreactor, change rate at which timer is
            % running
            if app.serial_port ~= "NULL"
                set(app.bioreactor_read_timer, 'Period', app.data_freq);
            end
        end

        % Value changed function: PlotDataCheckBox
        function plot_data_switched(app, event)
            value = app.PlotDataCheckBox.Value;
            
            if value == false
                cla(app.UIAxes);
                app.data_line_object.Visible = 'off';
                update_heartbeat_waveform(app);
            elseif value == true
                app.data_line_object.XData = [];
                app.data_line_object.YData = [];
                app.data_line_object.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BioreactorMenu = uimenu(app.UIFigure);
            app.BioreactorMenu.Text = 'Bioreactor';
            app.DocumentationMenu = uimenu(app.UIFigure);
            app.DocumentationMenu.Text = 'Documentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Pressure [mmHg]')
            app.OperatorDropDownLabel = uilabel(app.UIFigure);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorDropDown = uidropdown(app.UIFigure);
            app.OperatorDropDown.Items = {'Operator', 'Ted'};
            app.OperatorDropDown.ValueChangedFcn = createCallbackFcn(app, @operator_change, true);
            app.OperatorDropDown.Value = 'Operator';
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.Items = {'Single Valve'};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.ExperimentDropDown.Value = 'Single Valve';
            app.SystemRunningLamp = uilamp(app.UIFigure);
            app.RUNSwitchLabel = uilabel(app.UIFigure);
            app.RUNSwitchLabel.Text = 'RUN';
            app.RUNSwitch = uiswitch(app.UIFigure, 'slider');
            app.RUNSwitch.ValueChangedFcn = createCallbackFcn(app, @runBioreactor, true);
            app.PeakPressureEditFieldLabel = uilabel(app.UIFigure);
            app.PeakPressureEditFieldLabel.Text = {'Peak'; 'Pressure'};
            app.PeakPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakPressureEditField.ValueChangedFcn = createCallbackFcn(app, @peak_pressure_change, true);
            app.SaveDataCheckBox = uicheckbox(app.UIFigure);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveLocationHVBioreactorDataLabel = uilabel(app.UIFigure);
            app.SaveLocationHVBioreactorDataLabel.Text = 'Save Location: .../HVBioreactor/Data';
            app.MinPressureEditFieldLabel = uilabel(app.UIFigure);
            app.MinPressureEditFieldLabel.Text = {'Min'; 'Pressure'};
            app.MinPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPressureEditField.ValueChangedFcn = createCallbackFcn(app, @min_pressure_change, true);
            app.DataAcquisitionFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.DataAcquisitionFrequencyHzEditFieldLabel.Text = {'Data Acquisition'; 'Frequency (Hz)'};
            app.DataAcquisitionFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataAcquisitionFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @data_freq_changed, true);
            app.SystemReadyLampLabel = uilabel(app.UIFigure);
            app.SystemReadyLampLabel.Text = 'System Ready';
            app.SystemReadyLamp = uilamp(app.UIFigure);
            app.TimeForwardEditFieldLabel = uilabel(app.UIFigure);
            app.TimeForwardEditFieldLabel.Text = {'Time'; 'Forward'};
            app.TimeForwardEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeForwardEditField.ValueChangedFcn = createCallbackFcn(app, @time_for_change, true);
            app.TimeBackpressureEditFieldLabel = uilabel(app.UIFigure);
            app.TimeBackpressureEditFieldLabel.Text = {'Time'; 'Backpressure'};
            app.TimeBackpressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeBackpressureEditField.ValueChangedFcn = createCallbackFcn(app, @time_back_change, true);
            app.TimeRestEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRestEditFieldLabel.Text = {'Time'; 'Rest'};
            app.TimeRestEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeRestEditField.ValueChangedFcn = createCallbackFcn(app, @time_rest_change, true);
            app.UPDATEBIOREACTORButton = uibutton(app.UIFigure, 'push');
            app.UPDATEBIOREACTORButton.ButtonPushedFcn = createCallbackFcn(app, @update_bioreactor, true);
            app.UPDATEBIOREACTORButton.Text = {'UPDATE'; 'BIOREACTOR'};
            app.CONNECTBIOREACTORButton = uibutton(app.UIFigure, 'push');
            app.CONNECTBIOREACTORButton.ButtonPushedFcn = createCallbackFcn(app, @connect_bioreactor, true);
            app.CONNECTBIOREACTORButton.Text = {'CONNECT'; 'BIOREACTOR'};
            app.BIOREACTOROFFLabel = uilabel(app.UIFigure);
            app.BIOREACTOROFFLabel.Text = 'BIOREACTOR OFF';
            app.PlotDataCheckBox = uicheckbox(app.UIFigure);
            app.PlotDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @plot_data_switched, true);
            app.PlotDataCheckBox.Text = 'Plot Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bioreactor_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end